classdef CubeRotation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        RotationVectorLabel_2       matlab.ui.control.Label
        AxisAngleLabel              matlab.ui.control.Label
        EulerAnglesLabel_2          matlab.ui.control.Label
        QuaternionLabel_2           matlab.ui.control.Label
        RotationVectorDisplay       matlab.ui.control.Label
        PrincipalAxisAngleDispaly   matlab.ui.control.Label
        EulerAnglesDisplay          matlab.ui.control.Label
        QuaternionDisplay           matlab.ui.control.Label
        R32                         matlab.ui.control.Label
        R31                         matlab.ui.control.Label
        R21                         matlab.ui.control.Label
        R33                         matlab.ui.control.Label
        R23                         matlab.ui.control.Label
        R22                         matlab.ui.control.Label
        R13                         matlab.ui.control.Label
        R12                         matlab.ui.control.Label
        R11                         matlab.ui.control.Label
        PrincipalAngleSpinnerLabel  matlab.ui.control.Label
        vzSpinnerLabel              matlab.ui.control.Label
        vySpinnerLabel              matlab.ui.control.Label
        vxSpinnerLabel              matlab.ui.control.Label
        SelectAxisDropDownLabel     matlab.ui.control.Label
        zSpinnerLabel               matlab.ui.control.Label
        ySpinnerLabel               matlab.ui.control.Label
        xSpinnerLabel               matlab.ui.control.Label
        wSpinnerLabel               matlab.ui.control.Label
        YawSpinnerLabel             matlab.ui.control.Label
        PitchSpinnerLabel           matlab.ui.control.Label
        RollSpinnerLabel            matlab.ui.control.Label
        RotationMatrixLabel         matlab.ui.control.Label
        PrincipalAxisButtonGroup    matlab.ui.container.ButtonGroup
        ZButton                     matlab.ui.control.RadioButton
        YButton                     matlab.ui.control.RadioButton
        XButton                     matlab.ui.control.RadioButton
        ResetButton                 matlab.ui.control.Button
        RotationVectorLabel         matlab.ui.control.Label
        EulerAnglesLabel            matlab.ui.control.Label
        EulerprincipalAngleandAxisLabel  matlab.ui.control.Label
        QuaternionLabel             matlab.ui.control.Label
        PrincipalAngleSpinner       matlab.ui.control.Spinner
        vzSpinner                   matlab.ui.control.Spinner
        vySpinner                   matlab.ui.control.Spinner
        vxSpinner                   matlab.ui.control.Spinner
        SelectAxisDropDown          matlab.ui.control.DropDown
        zSpinner                    matlab.ui.control.Spinner
        ySpinner                    matlab.ui.control.Spinner
        xSpinner                    matlab.ui.control.Spinner
        wSpinner                    matlab.ui.control.Spinner
        YawSpinner                  matlab.ui.control.Spinner
        PitchSpinner                matlab.ui.control.Spinner
        RollSpinner                 matlab.ui.control.Spinner
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Constant)
        A = [0 0 0];
        B = [1 0 0];
        C = [0 1 0];
        D = [0 0 1];
        E = [0 1 1];
        F = [1 0 1];
        G = [1 1 0];
        H = [1 1 1];


    end

    properties (Access = private)

        Cube = [CubeRotation.A;CubeRotation.B;CubeRotation.F;CubeRotation.H;CubeRotation.G;CubeRotation.C;CubeRotation.A;CubeRotation.D;CubeRotation.E;CubeRotation.H;CubeRotation.F;CubeRotation.D;CubeRotation.E;CubeRotation.C;CubeRotation.G;CubeRotation.B]; % cube created

        VertexMatrix = [0 0 0
                        1 0 0
                        1 1 0
                        0 1 0
                        0 0 1
                        1 0 1
                        1 1 1
                        0 1 1]-0.5;
        FacesMatrix = [1 2 6 5
                       2 3 7 6
                       3 4 8 7
                       4 1 5 8
                       1 2 3 4
                       5 6 7 8];

        Quaternion = [0;0;0;0] % w, x, y, z

        PrincipalAxis = [0;0;0]; % x, y, z
        PrinciaplAngle = 0;

        Roll = 0;
        Pitch =0;
        Yaw = 0;
       

        RotationVector = [0;0;0]; % x,y,z
        Angle =0;
        currentPatch;

    end
    
    methods (Access = private)

       function mult = QuaternionMultiplication(app,q,p)
        %Hamilton formula
        mult(1) = q(1)*p(1) - q(2)*p(2) - q(3)*p(3) - q(4)*p(4);
        mult(2) = q(1)*p(2) + q(2)*p(1) + q(3)*p(4) - q(4)*p(3);
        mult(3) = q(1)*p(3) - q(2)*p(4) + q(3)*p(1) + q(4)*p(2);
        mult(4) = q(1)*p(4) + q(2)*p(3) - q(3)*p(2) + q(4)*p(1);
       end

       function qc = Conjugate(app,q)
         qc(1) = q(1);
         qc(2) = q(2)*-1;
         qc(3) = q(3)*-1;
         qc(4) = q(4)*-1;
       end
        
       %Rotation Matrix from Components
      
       function [rotatedVertices, rm]  = RotationMatrixQuaternion(app,q) % roatation matrix by quaternion
     
        initialVertex = app.VertexMatrix;

        %Extract the values from Q, to meake it readable
        q0 = q(1);
        q1 = q(2);
        q2 = q(3);
        q3 = q(4);
         
        % First row of the rotation matrix
        r00 = 2 * (q0 * q0 + q1 * q1) - 1;
        r01 = 2 * (q1 * q2 - q0 * q3);
        r02 = 2 * (q1 * q3 + q0 * q2);
         
        % Second row of the rotation matrix
        r10 = 2 * (q1 * q2 + q0 * q3);
        r11 = 2 * (q0 * q0 + q2 * q2) - 1;
        r12 = 2 * (q2 * q3 - q0 * q1);
         
        %Third row of the rotation matrix
        r20 = 2 * (q1 * q3 - q0 * q2);
        r21 = 2 * (q2 * q3 + q0 * q1);
        r22 = 2 * (q0 * q0 + q3 * q3) - 1;
    
        rm = [r00,r01,r02; r10,r11,r12;r20,r21,r22];
        DisplayRoationMatrix(app,rm);
        rotatedVertices = initialVertex * rm;

       end

       function [rotatedVertices, rm]  = RotationMatrixEulerAngles(app,roll,pitch,yaw) % Rotation matrix by euler angles
            
          initialVertex = app.VertexMatrix;
       
          Rx = [1 0         0;
               0  cosd(roll) -sind(roll); 
               0  sind(roll) cosd(roll)];

          Ry = [cosd(pitch)  0    sind(pitch); 
                0           1    0;     
                -sind(pitch) 0    cosd(pitch)];

          Rz = [cosd(yaw) -sind(yaw) 0; 
                sind(yaw) cosd(yaw)  0; 
                0        0         1];
    
          rm = Rx*Ry*Rz;
          DisplayRoationMatrix(app,rm);
          rotatedVertices = initialVertex * rm;

       end

       function [rotatedVertices, rm] = RotationMatrixPrincipalAxis(app, axis, angle) % Rotation matrix by angle and axis
             
          initialVertex = app.VertexMatrix;
           
          %rodriguez formula
          M = [axis(1)^2+(1-axis(1)^2)*cosd(angle)                  axis(1)*axis(2)*(1-cosd(angle))-axis(3)*sind(angle)    axis(1)*axis(3)*(1-cosd(angle))+axis(2)*sind(angle);
               axis(1)*axis(2)*(1-cosd(angle))+axis(3)*sind(angle)  axis(2)^2+(1-axis(2)^2)*cosd(angle)                    axis(2)*axis(3)*(1-cosd(angle))-axis(1)*sind(angle);
               axis(1)*axis(3)*(1-cosd(angle))-axis(2)*sind(angle)  axis(2)*axis(3)*(1-cosd(angle))+axis(1)*sind(angle)    axis(3)^2+(1-axis(3)^2)*cosd(angle)];
         
          rm = M;
          DisplayRoationMatrix(app,rm);
          rotatedVertices = initialVertex * rm;

       end

       %From Rotation Matrix to Components
       function [roll, pitch,yaw] = GetEulerAnglesFromMatrix(app, rotationMatrix)

           beta = -asind(rotationMatrix(3,1));
           gamma = atan2d(rotationMatrix(1,1) / cosd(beta) ,  rotationMatrix(2,1) / cosd(beta));
           alpha = atan2d(rotationMatrix(3,1) / cosd(beta) , rotationMatrix(3,3) / cos(beta));

           if(alpha < 0 ) alpha = alpha + 180; end
           if(gamma < 0 ) gamma = gamma + 180; end
           
           roll = beta;
           pitch = gamma;
           yaw = alpha;

       end    
        
       function [axis, angle] = GetAxisAngleFromMatrix(app,rotationMatrix)
       
        rm = rotationMatrix;

        angle = acosd((GetTrace(app,rm)-1) /2);
        
        while angle > 360 
            angle = angle - 360;
        end
        transposed = transpose(rm);

        matrixVectorProduct = (rm - transposed) / (2*sind(angle));

        axis(1) = (matrixVectorProduct(3,2) - matrixVectorProduct(2,3) / 2);
        axis(2) = (matrixVectorProduct(1,3) - matrixVectorProduct(3,1) / 2);
        axis(3) = (matrixVectorProduct(2,1) - matrixVectorProduct(1,2) / 2);
       end

       function quaternion = GetQuaternionFromAxisAngle(app,axis,angle)
        quaternion(1) = cosd(angle /2);
        quaternion(2) = axis(1) * sind(angle/2);
        quaternion(3) = axis(2) * sind(angle/2);
        quaternion(4) = axis(3) * sind(angle/2);
       end

       function rotationVector  = GetVectorFromAxisAngle(app, axis, angle)
        rotationVector(1) = axis(1)*angle;
        rotationVector(2) = axis(2)*angle;
        rotationVector(3) = axis(3)*angle;
       end

       function trace = GetTrace(app, matrix)
        a = 0;
        for i = 1:3
            a = a + matrix(i,i);
        end
        trace = a;
       end

       %Draw functions
       function DrawCube(app,vertexMatrix)   

            delete(app.currentPatch);
            view(app.UIAxes,3);
            app.currentPatch =  patch(app.UIAxes,'Faces',app.FacesMatrix,'Vertices',vertexMatrix,'FaceVertexCData',(1:6)','FaceColor','flat');
            alpha(app.UIAxes, 0.85);  
            
       end

       function DisplayRoationMatrix(app,rm)
              app.R11.Text = string( round(rm(1,1),3));
              app.R12.Text = string( round(rm(1,2),3));
              app.R13.Text = string( round(rm(1,3),3));

              app.R21.Text = string( round(rm(2,1),3));
              app.R22.Text = string( round(rm(2,2),3));
              app.R23.Text = string( round(rm(2,3),3));

              app.R31.Text = string( round(rm(3,1),3));
              app.R32.Text = string( round(rm(3,2),3));
              app.R33.Text = string( round(rm(3,3),3));

       end

       function DisplayAllComponentsFromRotationMatrix(app, rm)
        %this function displays all de other components according to a
        %modifycation 

          %[roll,pitch,yaw] = GetEulerAnglesFromMatrix(app,rm);
          %[pAxis,pAngle] = GetAxisAngleFromMatrix(app,rm);
          %quaternion = GetQuaternionFromAxisAngle(app,pAxis,pAngle);
          %rotationVector =  GetVectorFromAxisAngle(app,pAxis,pAngle);
          %q = strcat('q' , quaternion(1),quaternion(2),quaternion(3),quaternion(4));
          % app.QuaternionDisplay.Text = q;

       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           DrawCube(app,app.VertexMatrix);
        
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                       
            app.RollSpinner.Value = 0;
            app.PitchSpinner.Value = 0;
            app.YawSpinner.Value = 0;

            app.wSpinner.Value = 0;
            app.xSpinner.Value = 0;
            app.ySpinner.Value = 0;
            app.zSpinner.Value = 0;

            app.vxSpinner.Value = 0;
            app.vySpinner.Value = 0;
            app.vzSpinner.Value = 0;

            app.R11.Text = '0';
            app.R12.Text = '0';
            app.R13.Text = '0';
            app.R21.Text = '0';
            app.R22.Text = '0';
            app.R23.Text = '0';
            app.R31.Text = '0';
            app.R32.Text = '0';
            app.R33.Text = '0';
         
            DrawCube(app,app.VertexMatrix);
        end

        % Value changed function: PitchSpinner, RollSpinner, YawSpinner
        function RollSpinnerValueChanged(app, event)
            roll = app.RollSpinner.Value;
            pitch = app.PitchSpinner.Value;
            yaw = app.YawSpinner.Value;

            [rvm ,rm]= RotationMatrixEulerAngles(app,roll,pitch,yaw);   
             DisplayAllComponentsFromRotationMatrix(app, rm);

            DrawCube(app,rvm);
        end

        % Value changed function: wSpinner, xSpinner, ySpinner, zSpinner
        function wSpinnerValueChanged(app, event)
            w = app.wSpinner.Value;
            x = app.xSpinner.Value;
            y = app.ySpinner.Value;
            z = app.zSpinner.Value;
            
            q = [w,x,y,z];

            [rvm,rm ]= RotationMatrixQuaternion(app,q);

            DisplayAllComponentsFromRotationMatrix (app,rm,'Quaternions');

            DrawCube(app,rvm);
            
        end

        % Value changed function: SelectAxisDropDown, vxSpinner, 
        % vySpinner, vzSpinner
        function SelectAxisDropDownValueChanged(app, event)
            option = app.SelectAxisDropDown.Value;
            % Set the axis 
            switch(option)
                case 'X'
                    axis = [1 0 0];
                case 'Y'
                    axis = [0 1 0];
                case 'Z'
                    axis = [0 0 1];
            end
            
            x = app.vxSpinner.Value;
            y = app.vySpinner.Value;
            z = app.vzSpinner.Value;

            vector = [x,y,z];
            angle = norm(vector);

            for i = 3
                axis(i) = vector(i) / angle; 
            end
            
            rvm = RotationMatrixPrincipalAxis(app,axis,angle);


            DrawCube(app,rvm);
            
        end

        % Callback function: PrincipalAngleSpinner, PrincipalAxisButtonGroup
        function PrincipalAxisButtonGroupSelectionChanged(app, event)
            principalAxis = app.PrincipalAxisButtonGroup.SelectedObject.Text;
            angle = app.PrincipalAngleSpinner.Value;

   
            switch(principalAxis)
                case 'X'
                    axis = [1 0 0];
                case 'Y'
                    axis = [0 1 0];
                case 'Z'
                    axis = [0 0 1];
            end

            rvm = RotationMatrixPrincipalAxis(app,axis,angle);           
            DrawCube(app,rvm);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Cube')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RollSpinner = uispinner(app.GridLayout);
            app.RollSpinner.ValueChangedFcn = createCallbackFcn(app, @RollSpinnerValueChanged, true);
            app.PitchSpinner = uispinner(app.GridLayout);
            app.PitchSpinner.ValueChangedFcn = createCallbackFcn(app, @RollSpinnerValueChanged, true);
            app.YawSpinner = uispinner(app.GridLayout);
            app.YawSpinner.ValueChangedFcn = createCallbackFcn(app, @RollSpinnerValueChanged, true);
            app.wSpinner = uispinner(app.GridLayout);
            app.wSpinner.Step = 0.1;
            app.wSpinner.Limits = [-1 1];
            app.wSpinner.ValueChangedFcn = createCallbackFcn(app, @wSpinnerValueChanged, true);
            app.xSpinner = uispinner(app.GridLayout);
            app.xSpinner.Step = 0.1;
            app.xSpinner.Limits = [-1 1];
            app.xSpinner.ValueChangedFcn = createCallbackFcn(app, @wSpinnerValueChanged, true);
            app.ySpinner = uispinner(app.GridLayout);
            app.ySpinner.Step = 0.1;
            app.ySpinner.Limits = [-1 1];
            app.ySpinner.ValueChangedFcn = createCallbackFcn(app, @wSpinnerValueChanged, true);
            app.zSpinner = uispinner(app.GridLayout);
            app.zSpinner.Step = 0.1;
            app.zSpinner.Limits = [-1 1];
            app.zSpinner.ValueChangedFcn = createCallbackFcn(app, @wSpinnerValueChanged, true);
            app.SelectAxisDropDown = uidropdown(app.GridLayout);
            app.SelectAxisDropDown.Items = {'X', 'Y', 'Z'};
            app.SelectAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAxisDropDownValueChanged, true);
            app.SelectAxisDropDown.Value = 'X';
            app.vxSpinner = uispinner(app.GridLayout);
            app.vxSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectAxisDropDownValueChanged, true);
            app.vySpinner = uispinner(app.GridLayout);
            app.vySpinner.ValueChangedFcn = createCallbackFcn(app, @SelectAxisDropDownValueChanged, true);
            app.vzSpinner = uispinner(app.GridLayout);
            app.vzSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectAxisDropDownValueChanged, true);
            app.PrincipalAngleSpinner = uispinner(app.GridLayout);
            app.PrincipalAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @PrincipalAxisButtonGroupSelectionChanged, true);
            app.QuaternionLabel = uilabel(app.GridLayout);
            app.QuaternionLabel.Text = 'Quaternion';
            app.EulerprincipalAngleandAxisLabel = uilabel(app.GridLayout);
            app.EulerprincipalAngleandAxisLabel.Text = 'Euler principal Angle and Axis';
            app.EulerAnglesLabel = uilabel(app.GridLayout);
            app.EulerAnglesLabel.Text = 'Euler Angles';
            app.RotationVectorLabel = uilabel(app.GridLayout);
            app.RotationVectorLabel.Text = 'Rotation Vector';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PrincipalAxisButtonGroup = uibuttongroup(app.GridLayout);
            app.PrincipalAxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PrincipalAxisButtonGroupSelectionChanged, true);
            app.PrincipalAxisButtonGroup.Title = 'Principal Axis';
            app.XButton = uiradiobutton(app.PrincipalAxisButtonGroup);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uiradiobutton(app.PrincipalAxisButtonGroup);
            app.YButton.Text = 'Y';
            app.ZButton = uiradiobutton(app.PrincipalAxisButtonGroup);
            app.ZButton.Text = 'Z';
            app.RotationMatrixLabel = uilabel(app.GridLayout);
            app.RotationMatrixLabel.Text = 'Rotation Matrix';
            app.RollSpinnerLabel = uilabel(app.GridLayout);
            app.RollSpinnerLabel.Text = 'Roll';
            app.PitchSpinnerLabel = uilabel(app.GridLayout);
            app.PitchSpinnerLabel.Text = 'Pitch';
            app.YawSpinnerLabel = uilabel(app.GridLayout);
            app.YawSpinnerLabel.Text = 'Yaw';
            app.wSpinnerLabel = uilabel(app.GridLayout);
            app.wSpinnerLabel.Text = 'w';
            app.xSpinnerLabel = uilabel(app.GridLayout);
            app.xSpinnerLabel.Text = 'x';
            app.ySpinnerLabel = uilabel(app.GridLayout);
            app.ySpinnerLabel.Text = 'y';
            app.zSpinnerLabel = uilabel(app.GridLayout);
            app.zSpinnerLabel.Text = 'z';
            app.SelectAxisDropDownLabel = uilabel(app.GridLayout);
            app.SelectAxisDropDownLabel.Text = 'Select Axis';
            app.vxSpinnerLabel = uilabel(app.GridLayout);
            app.vxSpinnerLabel.Text = 'vx';
            app.vySpinnerLabel = uilabel(app.GridLayout);
            app.vySpinnerLabel.Text = 'vy';
            app.vzSpinnerLabel = uilabel(app.GridLayout);
            app.vzSpinnerLabel.Text = 'vz';
            app.PrincipalAngleSpinnerLabel = uilabel(app.GridLayout);
            app.PrincipalAngleSpinnerLabel.Text = 'Principal Angle';
            app.R11 = uilabel(app.GridLayout);
            app.R11.Text = 'R11';
            app.R12 = uilabel(app.GridLayout);
            app.R12.Text = 'R12';
            app.R13 = uilabel(app.GridLayout);
            app.R13.Text = 'R13';
            app.R22 = uilabel(app.GridLayout);
            app.R22.Text = 'R22';
            app.R23 = uilabel(app.GridLayout);
            app.R23.Text = 'R23';
            app.R33 = uilabel(app.GridLayout);
            app.R33.Text = 'R33';
            app.R21 = uilabel(app.GridLayout);
            app.R21.Text = 'R21';
            app.R31 = uilabel(app.GridLayout);
            app.R31.Text = 'R31';
            app.R32 = uilabel(app.GridLayout);
            app.R32.Text = 'R32';
            app.QuaternionDisplay = uilabel(app.GridLayout);
            app.EulerAnglesDisplay = uilabel(app.GridLayout);
            app.EulerAnglesDisplay.Text = 'Label2';
            app.PrincipalAxisAngleDispaly = uilabel(app.GridLayout);
            app.PrincipalAxisAngleDispaly.Text = 'Label3';
            app.RotationVectorDisplay = uilabel(app.GridLayout);
            app.RotationVectorDisplay.Text = 'Label4';
            app.QuaternionLabel_2 = uilabel(app.GridLayout);
            app.QuaternionLabel_2.Text = 'Quaternion';
            app.EulerAnglesLabel_2 = uilabel(app.GridLayout);
            app.EulerAnglesLabel_2.Text = 'Euler Angles';
            app.AxisAngleLabel = uilabel(app.GridLayout);
            app.AxisAngleLabel.Text = 'Axis&Angle';
            app.RotationVectorLabel_2 = uilabel(app.GridLayout);
            app.RotationVectorLabel_2.Text = 'Rotation Vector';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CubeRotation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end